classdef FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SinglePlayerButton       matlab.ui.control.Button
        TwoPlayerButton          matlab.ui.control.Button
        BlackjackLabel           matlab.ui.control.Label
        InDevlopmentLabel        matlab.ui.control.Label
        ReturnButton             matlab.ui.control.Button
        Player1Card1             matlab.ui.control.Image
        Player1Card5             matlab.ui.control.Image
        Player1Card4             matlab.ui.control.Image
        Player1Card3             matlab.ui.control.Image
        Player1Card2             matlab.ui.control.Image
        Player2Card1             matlab.ui.control.Image
        Player2Card5             matlab.ui.control.Image
        Player2Card4             matlab.ui.control.Image
        Player2Card3             matlab.ui.control.Image
        Player2Card2             matlab.ui.control.Image
        HitButton                matlab.ui.control.Button
        StayButton               matlab.ui.control.Button
        Player1ScoreLabel        matlab.ui.control.Label
        ReturntoMainMenuButton   matlab.ui.control.Button
        Player2ScoreLabel        matlab.ui.control.Label
        HitButton_2              matlab.ui.control.Button
        StayButton_2             matlab.ui.control.Button
        Winner                   matlab.ui.control.Label
        ScoreBoard               matlab.ui.control.Label
        PlayAgainButton          matlab.ui.control.StateButton
        Player2Card6             matlab.ui.control.Image
        Player1Card6             matlab.ui.control.Image
        ThreePlayerButton        matlab.ui.control.Button
        QuitButton               matlab.ui.control.Button
        RulesButton              matlab.ui.control.Button
        SwitchLabel              matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        EnterNameEditFieldLabel  matlab.ui.control.Label
        EnterNameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        hitButtonState = false % hit for player 1
        stayButtonState % stay for player 1
        hitButton2State % hit for player 2
        stayButton2State % stay for player 2
        playAgain % creates a new hand
        
        Fs% arguments for sound file
        y % argument for sound file
        myPlayerNum % Description
        Property9 % Description
    end
    
    methods (Access = private)
        %Creates table for with card names and values
        function [deck] = LoadDeck (~)
            deck = readtable('CardValues.xlsx');
        end
        
        %function shuffles deck/ creates a permuation of 52 numbers from 1 to 52.
        function [shuffledOrder] = ShuffleDeck(~) 
    
            shuffledOrder = randperm(52);

        end
        
        %Gives the player their Hand Value
        function [player1HandValue, player2HandValue] = UpdatePlayerHandValue(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck) 
            player1HandValue = [];
            for i = 1:1:numberOfPlayer1Cards                   
    
                player1HandValue = sum([player1HandValue, deck.CardValues(shuffledOrder(i))]);
    
            end
            app.Player1ScoreLabel.Text = append("Player 1 Hand Value: ", int2str(player1HandValue));
            player2HandValue = [];
            for i = 1:1:numberOfPlayer2Cards                   
    
                player2HandValue = sum([player2HandValue, deck.CardValues(shuffledOrder(i+20))]);
    
            end
            app.Player2ScoreLabel.Text = append("Player 2 Hand Value: ", int2str(player2HandValue));
        end

        %Gives the player their cards *invisible though
        function [player1Cards,player2Cards] = GivePlayerCards(~,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck) 
            player1Cards = [];
            player2Cards = [];
            for i = 1:1:numberOfPlayer1Cards                   
    
                player1Cards = string([player1Cards, deck.CardNames(shuffledOrder(i))]);
    
            end
            for i = 1:1:numberOfPlayer2Cards
                player2Cards = string([player2Cards, deck.CardNames(shuffledOrder(i+20))]);
            end
        end
        
        %can't figure out how to use a string to change a class definition
        %in an app, figure this out later, the method is very inefficient
        %but im at my wits end
        function [] = UpdatePlayerCards (app,player1Cards,player2Cards,numberOfPlayer1Cards,numberOfPlayer2Cards)
            for i = 1:1:numberOfPlayer1Cards
                cardLocation = char(append(player1Cards(i),".PNG"));
                if i == 1
                    app.Player1Card1.ImageSource = cardLocation;
                    app.Player1Card1.Visible = 'on';
                elseif i == 2
                    app.Player1Card2.ImageSource = cardLocation;
                    app.Player1Card2.Visible = 'on';
                elseif i ==3
                    app.Player1Card3.ImageSource = cardLocation;
                    app.Player1Card3.Visible = 'on';
                elseif i == 4
                    app.Player1Card4.ImageSource = cardLocation;
                    app.Player1Card4.Visible = 'on';
                elseif i == 5
                    app.Player1Card5.ImageSource = cardLocation;
                    app.Player1Card5.Visible = 'on';
                else
                    app.Player1Card6.ImageSource = cardLocation;
                    app.Player1Card6.Visible = 'on';
                end
            end
            for i = 1:1:numberOfPlayer2Cards
                cardLocation = char(append(player2Cards(i),".PNG"));
                if i == 1
                    app.Player2Card1.ImageSource = cardLocation;
                    app.Player2Card1.Visible = 'on';
                elseif i == 2
                    app.Player2Card2.ImageSource = cardLocation;
                    app.Player2Card2.Visible = 'on';
                elseif i ==3
                    app.Player2Card3.ImageSource = cardLocation;
                    app.Player2Card3.Visible = 'on';
                elseif i == 4
                    app.Player2Card4.ImageSource = cardLocation;
                    app.Player2Card4.Visible = 'on';
                elseif i == 5
                    app.Player1Card5.ImageSource = cardLocation;
                    app.Player1Card5.Visible = 'on';
                else
                    app.Player1Card6.ImageSource = cardLocation;
                    app.Player1Card6.Visible = 'on';
                end
            end 
        end
        
        %increaments the number of cards by one
        function [numberOfPlayer1Cards] = GivePlayer1CardsPlusOne(~,numberOfPlayer1Cards)
            numberOfPlayer1Cards = numberOfPlayer1Cards + 1;
        end
        function [numberOfPlayer2Cards] = GivePlayer2CardsPlusOne(~,numberOfPlayer2Cards)
            numberOfPlayer2Cards = numberOfPlayer2Cards + 1;
        end
        
        %compares hand values
        function [winner] = CompareScores(~,player1HandValue, player2HandValue)
            if player1HandValue > player2HandValue
                winner = 1;
            elseif player2HandValue > player1HandValue
                winner = 2;
            elseif player1HandValue == player2HandValue
                winner = 0;
            end
        end
        
        %Opens Two Player Mode
        function [] = OpenTwoPlayerScreen(app)
            CloseTitleCard(app);
            app.HitButton.Visible = 'on';
            app.StayButton.Visible = 'on';
            app.Player1ScoreLabel.Visible = 'on';
            app.HitButton_2.Visible = 'on';
            app.StayButton_2.Visible = 'on';
            app.Player2ScoreLabel.Visible = 'on';
            app.ReturntoMainMenuButton.Visible = 'on';
        end
        
        
        %closes Two Player mode
        function [] = CloseTwoPlayerScreen(app)
            app.HitButton.Visible = 'off';
            app.StayButton.Visible = 'off';
            app.Player1ScoreLabel.Visible = 'off';
            
            app.Player1Card1.Visible = 'off';
            app.Player1Card2.Visible = 'off';
            app.Player1Card3.Visible = 'off';
            app.Player1Card4.Visible = 'off';
            app.Player1Card5.Visible = 'off';
            app.Player1Card6.Visible = 'off';
            
            app.Player2Card1.Visible = 'off';
            app.Player2Card2.Visible = 'off';
            app.Player2Card3.Visible = 'off';
            app.Player2Card4.Visible = 'off';
            app.Player2Card5.Visible = 'off';
            app.Player2Card6.Visible = 'off';
            
            
        end
        
        %Turns off the visiblity of the title screen's buttons and labels
        function [] = CloseTitleCard(app)
            app.SinglePlayerButton.Visible = 'off';
            app.SinglePlayerButton.Enable = 'off';
            app.TwoPlayerButton.Visible = 'off';
            app.TwoPlayerButton.Enable ='off';
            
        end
        
        %Turns on the visibility of the 
        function [] = ReturnToTitleCard(app)
            app.SinglePlayerButton.Visible = 'on';
            app.SinglePlayerButton.Enable = 'on';
            app.TwoPlayerButton.Visible = 'on';
            app.TwoPlayerButton.Enable ='on';
            
        end
        %function for accesssing sound files
        function [] = soundPlayer(app, number)
            switch number
                case 1 % game won
                    [ySound, samplefreq]=audioread('Winningsoundeffect.mp3')
                    %app.Player= audioplayer(ySound, samplefreq);
                    sound(ySound, samplefreq)
                   
                case 2 % game lost
                    [ySound, samplefreq]=audioread('Youlosegameover.mp3')
                    sound(ySound, samplefreq)
                    
                case 3 % cards shuffling
                    [ySound, samplefreq]=audioread('shufflingcards1.mp3')
                    sound(ySound, samplefreq)
             
                    
                case 4 % game start
                    [ySound, samplefreq]=audioread('Risinglogo.mp3')
                    sound(ySound, samplefreq)
  
                    
            end
            
        end
        % value changed function
        function []=MusicSwitchValue(app,event)
            value=app.MusicSwitch.Value;
            [ySound, samplefreq]=audioread('shufflingcards1.mp3');
            app.Player =audioplayer(ySound, samplefreq);
            
            if strcmp(value, 'On')
                resume(app.Player)
            end

            
        end
        
        function []=confetti(app)
            for i=550:-8:-650
                app.confettiImage.Position=[5,i,934,681];
                pause(0.1);
            end
            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             %starts the background audio
            [ySound, samplefreq]=audioread('shufflingcards1.mp3');
            sound(ySound, samplefreq);
            
            app.myPlayerNum=1;
            app.otherPlayerNum=2;
            app.winning_score=0;
            app.counter=0;
            
            
        end

        % Button pushed function: SinglePlayerButton
        function StartSinglePlayer(app, event)
          
            app.SinglePlayerButton.Visible = 'off';
            app.SinglePlayerButton.Enable = 'off';
            app.TwoPlayerButton.Visible = 'off';
            app.TwoPlayerButton.Enable ='off';
            
            app.InDevlopmentLabel.Visible = 'on';
            app.ReturnButton.Visible = 'on';
            app.ReturnButton.Enable = 'on';
        end

        % Button pushed function: TwoPlayerButton
        function StartTwoPlayer(app, event)
            OpenTwoPlayerScreen(app)
            gamestate = true;
            playerTurn = 1;
            player1Stay = false;
            player2Stay = false;
            player1Score = 0;
            player2Score = 0;
            overshoot = false;
            app.ScoreBoard.Text = append("Scoreboard:  ","Player 1:  ",int2str(player1Score),"     ","Player 2:  ",int2str(player2Score));
            numberOfPlayer1Cards = 2;
            numberOfPlayer2Cards = 2;
            shuffledOrder = ShuffleDeck(app);
            deck = LoadDeck(app);
           
            while gamestate == true
               
                
                % Updates player cards
               
                [player1Cards,player2Cards] = GivePlayerCards(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck);
                UpdatePlayerCards(app,player1Cards,player2Cards,numberOfPlayer1Cards,numberOfPlayer2Cards);
                [player1HandValue, player2HandValue] = UpdatePlayerHandValue(app,numberOfPlayer1Cards,numberOfPlayer2Cards,shuffledOrder,deck);
                
                if player1HandValue == 21 && player2HandValue == 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 0;
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    app.HitButton_2.Enable = 'off';
                    app.StayButton_2.Enable = 'off';
                elseif player1HandValue > 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 2;
                    overshoot = true;
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    app.HitButton_2.Enable = 'off';
                    app.StayButton_2.Enable = 'off';
                elseif player2HandValue > 21
                    player1Stay = true;
                    player2Stay = true;
                    winner = 1;
                    overshoot = true;
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    app.HitButton_2.Enable = 'off';
                    app.StayButton_2.Enable = 'off';
                end
                
                % enables or disables hit and stay buttons based on player
                % turn
                if player1Stay == true && player2Stay == true
                    if overshoot == false    
                        winner = CompareScores(app,player1HandValue, player2HandValue);
                    end
                    if winner == 1
                        app.Winner.Text = "Player 1 Wins";
                        player1Score = player1Score + 1;
                        app.ScoreBoard.Text = append("Scoreboard:  ", "Player 1:  ",int2str(player1Score),"     ","Player 2:  ",int2str(player2Score));
                        app.PlayAgainButton.Visible = 'on';
                    elseif winner == 2
                        app.Winner.Text = "Player 2 Wins";
                        player2Score = player2Score + 1;
                        app.ScoreBoard.Text = append("Scoreboard:  ", "Player 1:  ",int2str(player1Score),"     ","Player 2:  ",int2str(player2Score));
                        app.PlayAgainButton.Visible = 'on';
                    elseif winner == 0
                        app.Winner.Text = "It's a Tie";
                        app.PlayAgainButton.Visible = 'on';
                    end
                elseif playerTurn == 1 && player1Stay == false
                    app.HitButton.Enable = 'on';
                    app.StayButton.Enable = 'on';
                    app.HitButton_2.Enable = 'off';
                    app.StayButton_2.Enable = 'off';
                    if player1Stay == true
                        playerTurn = 2;
                    end
                    
                
                elseif playerTurn == 2 && player2Stay == false
                    app.HitButton.Enable = 'off';
                    app.StayButton.Enable = 'off';
                    app.HitButton_2.Enable = 'on';
                    app.StayButton_2.Enable = 'on';
                    if player2Stay == true
                        playerTurn = 1;
                    end
                end
                
                
                %waits for a player to hit or stay or new game
                uiwait(app.UIFigure)
                
                if app.playAgain == true
                    app.playAgain = false;
                    if winner == 1
                        playerTurn = 2;
                    elseif winner == 2
                        playerTurn = 1;
                    end
                    player1Stay = false;
                    player2Stay = false;
                    numberOfPlayer1Cards = 2;
                    numberOfPlayer2Cards = 2;
                    overshoot = false;
                    shuffledOrder = ShuffleDeck(app);
                    app.Player1Card1.Visible = 'off';
                    app.Player1Card2.Visible = 'off';
                    app.Player1Card3.Visible = 'off';
                    app.Player1Card4.Visible = 'off';
                    app.Player1Card5.Visible = 'off';
                    app.Player2Card1.Visible = 'off';
                    app.Player2Card2.Visible = 'off';
                    app.Player2Card3.Visible = 'off';
                    app.Player2Card4.Visible = 'off';
                    app.Player2Card5.Visible = 'off';
                    app.PlayAgainButton.Visible ='off';
                    app.Winner.Text = '';
                end
                
                %decision making when a hit or stay button is pushed
                if app.hitButtonState == true
                    numberOfPlayer1Cards = GivePlayer1CardsPlusOne(app,numberOfPlayer1Cards);
                    app.hitButtonState = false;
                    playerTurn = 2;
                
                elseif app.stayButtonState == true
                    player1Stay = true;
                    app.stayButtonState = false;
                    playerTurn = 2;
                
                elseif app.hitButton2State == true
                    numberOfPlayer2Cards = GivePlayer2CardsPlusOne(app,numberOfPlayer2Cards);
                    app.hitButton2State = false;
                    playerTurn = 1;
                
                elseif app.stayButton2State == true
                    player2Stay = true;
                    app.stayButton2State = false;
                    playerTurn = 1;
                end
                
            end
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            app.SinglePlayerButton.Visible = 'on';
            app.SinglePlayerButton.Enable = 'on';
            app.TwoPlayerButton.Visible = 'on';
            app.TwoPlayerButton.Enable ='on';
           
            app.InDevlopmentLabel.Visible = 'off';
            app.ReturnButton.Visible = 'off';
            app.ReturnButton.Enable = 'off';
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
           app.hitButtonState = true;
           uiresume(app.UIFigure)
        end

        % Button pushed function: StayButton
        function StayButtonPushed(app, event)
            app.stayButtonState = true;
            uiresume(app.UIFigure)
        end

        % Button pushed function: ReturntoMainMenuButton
        function ReturntoMainMenuButtonPushed(app, event)
            app.SinglePlayerButton.Visible = 'on';
            app.SinglePlayerButton.Enable = 'on';
            app.TwoPlayerButton.Visible = 'on';
            app.TwoPlayerButton.Enable ='on';
           
            
            app.HitButton.Visible = 'off';
            app.StayButton.Visible = 'off';
            app.Player1ScoreLabel.Visible = 'off';
            app.Player1Card1.Visible = 'off';
            app.Player1Card2.Visible = 'off';
            app.Player1Card3.Visible = 'off';
            app.Player1Card4.Visible = 'off';
            app.Player1Card5.Visible = 'off';
            app.HitButton_2.Visible = 'off';
            app.StayButton_2.Visible = 'off';
            app.Player2ScoreLabel.Visible = 'off';
            app.Player2Card1.Visible = 'off';
            app.Player2Card2.Visible = 'off';
            app.Player2Card3.Visible = 'off';
            app.Player2Card4.Visible = 'off';
            app.Player2Card5.Visible = 'off';
            
            app.ScoreBoard.Text = "";
            app.Winner.Text = "";
            app.PlayAgainButton.Visible ='off';
            
            app.ReturntoMainMenuButton.Visible = 'off';
            
        end

        % Button pushed function: HitButton_2
        function HitButton_2Pushed(app, event)
            app.hitButton2State = true;
            uiresume(app.UIFigure)
        end

        % Button pushed function: StayButton_2
        function StayButton_2Pushed(app, event)
            app.stayButton2State = true;
            uiresume(app.UIFigure)
        end

        % Value changed function: PlayAgainButton
        function PlayAgainButtonValueChanged(app, event)
            app.playAgain = true;
            uiresume(app.UIFigure)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value=app.MusicSwitch.Value;
            [ySound, samplefreq]=audioread('shufflingcards1.mp3');
            app.Player =audioplayer(ySound, samplefreq);
            
            if strcmp(value, 'On')
                resume(app.Player)
            
        end

        % Value changed function: EnterNameEditField
        function EnterNameEditFieldValueChanged(app, event)
            value = app.EnterNameEditField.Value;
            
        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            Rules
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.SinglePlayerButton = uibutton(app.UIFigure, 'push');
            app.SinglePlayerButton.ButtonPushedFcn = createCallbackFcn(app, @StartSinglePlayer, true);
            app.SinglePlayerButton.Text = {'Single Player'; ''};
            app.TwoPlayerButton = uibutton(app.UIFigure, 'push');
            app.TwoPlayerButton.ButtonPushedFcn = createCallbackFcn(app, @StartTwoPlayer, true);
            app.TwoPlayerButton.Text = {'Two Player'; ''};
            app.BlackjackLabel = uilabel(app.UIFigure);
            app.BlackjackLabel.Tag = 'TitleCard';
            app.BlackjackLabel.Text = 'Blackjack';
            app.InDevlopmentLabel = uilabel(app.UIFigure);
            app.InDevlopmentLabel.Enable = 'off';
            app.InDevlopmentLabel.Visible = 'off';
            app.InDevlopmentLabel.Text = 'In Devlopment';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Enable = 'off';
            app.ReturnButton.Visible = 'off';
            app.ReturnButton.Text = 'Return';
            app.Player1Card1 = uiimage(app.UIFigure);
            app.Player1Card1.Visible = 'off';
            app.Player1Card1.ImageSource = '2C.PNG';
            app.Player1Card5 = uiimage(app.UIFigure);
            app.Player1Card5.Visible = 'off';
            app.Player1Card5.ImageSource = '2C.PNG';
            app.Player1Card4 = uiimage(app.UIFigure);
            app.Player1Card4.Visible = 'off';
            app.Player1Card4.ImageSource = '2C.PNG';
            app.Player1Card3 = uiimage(app.UIFigure);
            app.Player1Card3.Visible = 'off';
            app.Player1Card3.ImageSource = '2C.PNG';
            app.Player1Card2 = uiimage(app.UIFigure);
            app.Player1Card2.Visible = 'off';
            app.Player1Card2.ImageSource = '2C.PNG';
            app.Player2Card1 = uiimage(app.UIFigure);
            app.Player2Card1.Visible = 'off';
            app.Player2Card1.ImageSource = '2C.PNG';
            app.Player2Card5 = uiimage(app.UIFigure);
            app.Player2Card5.Visible = 'off';
            app.Player2Card5.ImageSource = '2C.PNG';
            app.Player2Card4 = uiimage(app.UIFigure);
            app.Player2Card4.Visible = 'off';
            app.Player2Card4.ImageSource = '2C.PNG';
            app.Player2Card3 = uiimage(app.UIFigure);
            app.Player2Card3.Visible = 'off';
            app.Player2Card3.ImageSource = '2C.PNG';
            app.Player2Card2 = uiimage(app.UIFigure);
            app.Player2Card2.Visible = 'off';
            app.Player2Card2.ImageSource = '2C.PNG';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Visible = 'off';
            app.HitButton.Text = 'Hit';
            app.StayButton = uibutton(app.UIFigure, 'push');
            app.StayButton.ButtonPushedFcn = createCallbackFcn(app, @StayButtonPushed, true);
            app.StayButton.Visible = 'off';
            app.StayButton.Text = 'Stay';
            app.Player1ScoreLabel = uilabel(app.UIFigure);
            app.Player1ScoreLabel.Visible = 'off';
            app.Player1ScoreLabel.Text = 'Player 1 Score: ';
            app.ReturntoMainMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMainMenuButtonPushed, true);
            app.ReturntoMainMenuButton.Visible = 'off';
            app.ReturntoMainMenuButton.Text = 'Return to Main Menu';
            app.Player2ScoreLabel = uilabel(app.UIFigure);
            app.Player2ScoreLabel.Visible = 'off';
            app.Player2ScoreLabel.Text = 'Player 2 Score: ';
            app.HitButton_2 = uibutton(app.UIFigure, 'push');
            app.HitButton_2.ButtonPushedFcn = createCallbackFcn(app, @HitButton_2Pushed, true);
            app.HitButton_2.Visible = 'off';
            app.HitButton_2.Text = 'Hit';
            app.StayButton_2 = uibutton(app.UIFigure, 'push');
            app.StayButton_2.ButtonPushedFcn = createCallbackFcn(app, @StayButton_2Pushed, true);
            app.StayButton_2.Visible = 'off';
            app.StayButton_2.Text = 'Stay';
            app.Winner = uilabel(app.UIFigure);
            app.Winner.Text = '';
            app.ScoreBoard = uilabel(app.UIFigure);
            app.ScoreBoard.Text = '';
            app.PlayAgainButton = uibutton(app.UIFigure, 'state');
            app.PlayAgainButton.ValueChangedFcn = createCallbackFcn(app, @PlayAgainButtonValueChanged, true);
            app.PlayAgainButton.Visible = 'off';
            app.PlayAgainButton.Text = {'Play Again?'; ''};
            app.Player2Card6 = uiimage(app.UIFigure);
            app.Player2Card6.Visible = 'off';
            app.Player2Card6.ImageSource = '2C.PNG';
            app.Player1Card6 = uiimage(app.UIFigure);
            app.Player1Card6.Visible = 'off';
            app.Player1Card6.ImageSource = '2C.PNG';
            app.ThreePlayerButton = uibutton(app.UIFigure, 'push');
            app.ThreePlayerButton.Visible = 'off';
            app.ThreePlayerButton.Text = {'Three Player'; ''};
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.Visible = 'off';
            app.QuitButton.Text = {'Quit'; ''};
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.EnterNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterNameEditFieldLabel.Text = 'Enter Name';
            app.EnterNameEditField = uieditfield(app.UIFigure, 'text');
            app.EnterNameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterNameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end